classdef AudioEqualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ProcessingLabel         matlab.ui.control.Label
        ANALYZEButton           matlab.ui.control.Button
        KHzKnob_5_Detail        matlab.ui.control.Label
        KHzKnob_4_Detail        matlab.ui.control.Label
        KHzKnob_3_Detail        matlab.ui.control.Label
        KHzKnob_2_Detail        matlab.ui.control.Label
        KHzKnob_1_Detail        matlab.ui.control.Label
        HzKnob_4_Detail         matlab.ui.control.Label
        HzKnob_3_Detail         matlab.ui.control.Label
        HzKnob_2_Detail         matlab.ui.control.Label
        HzKnob_1_Detail         matlab.ui.control.Label
        FrequencyBands          matlab.ui.control.Label
        SAVEButton              matlab.ui.control.Button
        stopButton              matlab.ui.control.Button
        playButton              matlab.ui.control.Button
        RESETButton             matlab.ui.control.Button
        APPLYFILTERSButton      matlab.ui.control.Button
        KHzKnob_5               matlab.ui.control.Knob
        KHzKnob_5Label          matlab.ui.control.Label
        KHzKnob_4               matlab.ui.control.Knob
        KHzKnob_4Label          matlab.ui.control.Label
        KHzKnob_3               matlab.ui.control.Knob
        KHzKnob_3Label          matlab.ui.control.Label
        KHzKnob_2               matlab.ui.control.Knob
        KHzKnob_2Label          matlab.ui.control.Label
        KHzKnob_1               matlab.ui.control.Knob
        KHzKnobLabel            matlab.ui.control.Label
        HzKnob_4                matlab.ui.control.Knob
        HzKnob_11Label          matlab.ui.control.Label
        HzKnob_3                matlab.ui.control.Knob
        HzLabel                 matlab.ui.control.Label
        HzKnob_2                matlab.ui.control.Knob
        HzKnob_10Label          matlab.ui.control.Label
        HzKnob_1                matlab.ui.control.Knob
        HzKnobLabel             matlab.ui.control.Label
        sampleRateField         matlab.ui.control.NumericEditField
        sampleRateLabel         matlab.ui.control.Label
        SelectButton            matlab.ui.control.Button
        NOAUDIOFILECHOSENLabel  matlab.ui.control.Label
        FILTERTYPEButtonGroup   matlab.ui.container.ButtonGroup
        IIRButton               matlab.ui.control.RadioButton
        FIRButton               matlab.ui.control.RadioButton
        AUDIOEQUALIZERLabel     matlab.ui.control.Label
        filteredPlot            matlab.ui.control.UIAxes
        originalPlot            matlab.ui.control.UIAxes
    end


    properties (Access = public)
        originalSampleRate = 0 % SAMPLE RATE LOADED FROM AUDIO FILE
        sampleRate = 0         % SAMPLE RATE USED IN PROCESSING
        originalAudio          % AUDIO DATA LOADED FROM AUDIO FILE
        audio                  % AUDIO DATA USED IN PROCESSING (SR MAY DIFFER)
        fileName
        filePath
        filterClass = 0        % 0 FOR FIR & 1 FOR IIR
        enhancedAudio          % AUDIO DATA AFTER FILTERING
        frequencyBands = ones(1, 9)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file, path] = uigetfile('*.wav', 'Select File');
            if (file ~= 0)
                app.fileName = file;
                app.filePath = strcat(path, file);

                app.ProcessingLabel.Visible = 'on';

                [app.originalAudio, app.originalSampleRate] = audioread(app.filePath);

                app.sampleRateField.Value = app.originalSampleRate;
                app.sampleRateLabel.Text = strcat(' SAMPLE RATE:', {' '}, num2str(app.originalSampleRate));
                app.sampleRate = app.originalSampleRate;

                app.audio = app.originalAudio;
                app.NOAUDIOFILECHOSENLabel.Text = strcat(' AUDIO FILE CHOSEN:', {' '}, app.fileName);

                APPLYFILTERSButtonPushed(app, event);
                FILTERTYPEButtonGroupSelectionChanged(app, event);

                app.ProcessingLabel.Visible = 'off';
            end
        end

        % Selection changed function: FILTERTYPEButtonGroup
        function FILTERTYPEButtonGroupSelectionChanged(app, event)
            selectedButton = app.FILTERTYPEButtonGroup.SelectedObject;
            if(selectedButton == app.FIRButton)
                app.filterClass = 0;
                disp('using FIR');
            else
                app.filterClass = 1;
                disp('using IIR');
            end
        end

        % Value changed function: HzKnob_1
        function HzKnob_1ValueChanged(app, event)
            value = app.HzKnob_1.Value;
            app.frequencyBands(1) = 10^(value/20);
            app.FrequencyBands.Text = mat2str(app.frequencyBands, 2);
            app.HzKnob_1_Detail.Text = mat2str(10^(value/20), 2);
        end

        % Value changed function: HzKnob_2
        function HzKnob_2ValueChanged(app, event)
            value = app.HzKnob_2.Value;
            app.frequencyBands(2) = 10^(value/20);
            app.FrequencyBands.Text = mat2str(app.frequencyBands, 2);
            app.HzKnob_2_Detail.Text = mat2str(10^(value/20), 2);
        end

        % Value changed function: HzKnob_3
        function HzKnob_3ValueChanged(app, event)
            value = app.HzKnob_3.Value;
            app.frequencyBands(3) = 10^(value/20);
            app.FrequencyBands.Text = mat2str(app.frequencyBands, 2);
            app.HzKnob_3_Detail.Text = mat2str(10^(value/20), 2);
        end

        % Value changed function: HzKnob_4
        function HzKnob_4ValueChanged(app, event)
            value = app.HzKnob_4.Value;
            app.frequencyBands(4) = 10^(value/20);
            app.FrequencyBands.Text = mat2str(app.frequencyBands, 2);
            app.HzKnob_4_Detail.Text = mat2str(10^(value/20), 2);
        end

        % Value changed function: KHzKnob_1
        function KHzKnob_1ValueChanged(app, event)
            value = app.KHzKnob_1.Value;
            app.frequencyBands(5) = 10^(value/20);
            app.FrequencyBands.Text = mat2str(app.frequencyBands, 2);
            app.KHzKnob_1_Detail.Text = mat2str(10^(value/20), 2);
        end

        % Value changed function: KHzKnob_2
        function KHzKnob_2ValueChanged(app, event)
            value = app.KHzKnob_2.Value;
            app.frequencyBands(6) = 10^(value/20);
            app.FrequencyBands.Text = mat2str(app.frequencyBands, 2);
            app.KHzKnob_2_Detail.Text = mat2str(10^(value/20), 2);
        end

        % Value changed function: KHzKnob_3
        function KHzKnob_3ValueChanged(app, event)
            value = app.KHzKnob_3.Value;
            app.frequencyBands(7) = 10^(value/20);
            app.FrequencyBands.Text = mat2str(app.frequencyBands, 2);
            app.KHzKnob_3_Detail.Text = mat2str(10^(value/20), 2);
        end

        % Value changed function: KHzKnob_4
        function KHzKnob_4ValueChanged(app, event)
            value = app.KHzKnob_4.Value;
            app.frequencyBands(8) = 10^(value/20);
            app.FrequencyBands.Text = mat2str(app.frequencyBands, 2);
            app.KHzKnob_4_Detail.Text = mat2str(10^(value/20), 2);
        end

        % Value changed function: KHzKnob_5
        function KHzKnob_5ValueChanged(app, event)
            value = app.KHzKnob_5.Value;
            app.frequencyBands(9) = 10^(value/20);
            app.FrequencyBands.Text = mat2str(app.frequencyBands, 2);
            app.KHzKnob_5_Detail.Text = mat2str(10^(value/20), 2);
        end

        % Button pushed function: APPLYFILTERSButton
        function APPLYFILTERSButtonPushed(app, event)
            app.ProcessingLabel.Visible = 'on';
            
            app.enhancedAudio = apply_filters(app.originalAudio, app.originalSampleRate, app.frequencyBands, app.filterClass);

            plot(app.originalPlot, app.originalAudio);
            plot(app.filteredPlot, app.enhancedAudio);
            
            app.ProcessingLabel.Visible = 'off';
        end

        % Value changed function: sampleRateField
        function sampleRateFieldValueChanged(app, event)
            app.sampleRate = app.sampleRateField.Value;

            if (app.sampleRate == app.originalSampleRate) % 44100
                app.audio = app.originalAudio;                               
            elseif (app.sampleRate > app.originalSampleRate)
                app.audio = upsample(app.originalAudio, app.sampleRate / app.originalSampleRate);
                app.enhancedAudio = upsample(app.enhancedAudio, app.sampleRate / app.originalSampleRate);             
            else
                app.audio = downsample(app.originalAudio, app.originalSampleRate / app.sampleRate);
                app.enhancedAudio = downsample(app.enhancedAudio, app.originalSampleRate / app.sampleRate);
            end
            plot(app.originalPlot, app.audio);
            plot(app.filteredPlot, app.enhancedAudio);
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            clear sound;
            sound(app.enhancedAudio, app.sampleRate);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.ProcessingLabel.Visible = 'on';
            filename = 'enhanced.wav';
            audiowrite(filename, app.enhancedAudio, app.sampleRate);
            app.ProcessingLabel.Visible = 'off';
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.ProcessingLabel.Visible = 'on';
            app.sampleRate = app.originalSampleRate;
            app.frequencyBands = ones(1, 9);

            app.sampleRateField.Value = app.originalSampleRate;
            app.HzKnob_1.Value = 0;
            app.HzKnob_2.Value = 0;
            app.HzKnob_3.Value = 0;
            app.HzKnob_4.Value = 0;
            app.KHzKnob_1.Value = 0;
            app.KHzKnob_2.Value = 0;
            app.KHzKnob_3.Value = 0;
            app.KHzKnob_4.Value = 0;
            app.KHzKnob_5.Value = 0;

            apply_filters(app.originalAudio, app.originalSampleRate, app.frequencyBands, app.filterClass);
            app.ProcessingLabel.Visible = 'off';
        end

        % Button pushed function: ANALYZEButton
        function ANALYZEButtonPushed(app, event)
            app.ProcessingLabel.Visible = 'on';
            print_audio_analysis(app.originalAudio, app.originalSampleRate, app.frequencyBands, app.filterClass);
            app.ProcessingLabel.Visible = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear sound;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.originalPlot = uiaxes(app.UIFigure);
            title(app.originalPlot, 'ORIGINAL')
            app.filteredPlot = uiaxes(app.UIFigure);
            title(app.filteredPlot, 'ENHANCED')
            app.AUDIOEQUALIZERLabel = uilabel(app.UIFigure);
            app.AUDIOEQUALIZERLabel.Text = 'AUDIO EQUALIZER';
            app.FILTERTYPEButtonGroup = uibuttongroup(app.UIFigure);
            app.FILTERTYPEButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FILTERTYPEButtonGroupSelectionChanged, true);
            app.FILTERTYPEButtonGroup.Title = 'FILTER TYPE';
            app.FIRButton = uiradiobutton(app.FILTERTYPEButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.FIRButton.Value = true;
            app.IIRButton = uiradiobutton(app.FILTERTYPEButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.NOAUDIOFILECHOSENLabel = uilabel(app.UIFigure);
            app.NOAUDIOFILECHOSENLabel.Text = ' NO AUDIO FILE CHOSEN';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.sampleRateLabel = uilabel(app.UIFigure);
            app.sampleRateLabel.Text = ' ORIGINAL SAMPLE RATE: 0';
            app.sampleRateField = uieditfield(app.UIFigure, 'numeric');
            app.sampleRateField.ValueDisplayFormat = '%.0f';
            app.sampleRateField.ValueChangedFcn = createCallbackFcn(app, @sampleRateFieldValueChanged, true);
            app.HzKnobLabel = uilabel(app.UIFigure);
            app.HzKnobLabel.Text = '0 - 170 Hz';
            app.HzKnob_1 = uiknob(app.UIFigure, 'continuous');
            app.HzKnob_1.Limits = [-24 24];
            app.HzKnob_1.ValueChangedFcn = createCallbackFcn(app, @HzKnob_1ValueChanged, true);
            app.HzKnob_10Label = uilabel(app.UIFigure);
            app.HzKnob_10Label.Text = '170 -  310 Hz';
            app.HzKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.HzKnob_2.Limits = [-24 24];
            app.HzKnob_2.ValueChangedFcn = createCallbackFcn(app, @HzKnob_2ValueChanged, true);
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '310 - 600 Hz';
            app.HzKnob_3 = uiknob(app.UIFigure, 'continuous');
            app.HzKnob_3.Limits = [-24 24];
            app.HzKnob_3.ValueChangedFcn = createCallbackFcn(app, @HzKnob_3ValueChanged, true);
            app.HzKnob_11Label = uilabel(app.UIFigure);
            app.HzKnob_11Label.Text = '600 - 1000 Hz';
            app.HzKnob_4 = uiknob(app.UIFigure, 'continuous');
            app.HzKnob_4.Limits = [-24 24];
            app.HzKnob_4.ValueChangedFcn = createCallbackFcn(app, @HzKnob_4ValueChanged, true);
            app.KHzKnobLabel = uilabel(app.UIFigure);
            app.KHzKnobLabel.Text = '1 - 3 KHz';
            app.KHzKnob_1 = uiknob(app.UIFigure, 'continuous');
            app.KHzKnob_1.Limits = [-24 24];
            app.KHzKnob_1.ValueChangedFcn = createCallbackFcn(app, @KHzKnob_1ValueChanged, true);
            app.KHzKnob_2Label = uilabel(app.UIFigure);
            app.KHzKnob_2Label.Text = '3 - 6 KHz';
            app.KHzKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.KHzKnob_2.Limits = [-24 24];
            app.KHzKnob_2.ValueChangedFcn = createCallbackFcn(app, @KHzKnob_2ValueChanged, true);
            app.KHzKnob_3Label = uilabel(app.UIFigure);
            app.KHzKnob_3Label.Text = '6 - 12 KHz';
            app.KHzKnob_3 = uiknob(app.UIFigure, 'continuous');
            app.KHzKnob_3.Limits = [-24 24];
            app.KHzKnob_3.ValueChangedFcn = createCallbackFcn(app, @KHzKnob_3ValueChanged, true);
            app.KHzKnob_4Label = uilabel(app.UIFigure);
            app.KHzKnob_4Label.Text = '12 - 14 KHz';
            app.KHzKnob_4 = uiknob(app.UIFigure, 'continuous');
            app.KHzKnob_4.Limits = [-24 24];
            app.KHzKnob_4.ValueChangedFcn = createCallbackFcn(app, @KHzKnob_4ValueChanged, true);
            app.KHzKnob_5Label = uilabel(app.UIFigure);
            app.KHzKnob_5Label.Text = '14 - 16 KHz';
            app.KHzKnob_5 = uiknob(app.UIFigure, 'continuous');
            app.KHzKnob_5.Limits = [-24 24];
            app.KHzKnob_5.ValueChangedFcn = createCallbackFcn(app, @KHzKnob_5ValueChanged, true);
            app.APPLYFILTERSButton = uibutton(app.UIFigure, 'push');
            app.APPLYFILTERSButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYFILTERSButtonPushed, true);
            app.APPLYFILTERSButton.Text = 'APPLY FILTERS';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play.png';
            app.playButton.Text = '';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop.png';
            app.stopButton.Text = '';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.FrequencyBands = uilabel(app.UIFigure);
            app.FrequencyBands.Enable = 'off';
            app.FrequencyBands.Visible = 'off';
            app.FrequencyBands.Text = '[1 1 1 1 1 1 1 1 1]';
            app.HzKnob_1_Detail = uilabel(app.UIFigure);
            app.HzKnob_1_Detail.Text = '1';
            app.HzKnob_2_Detail = uilabel(app.UIFigure);
            app.HzKnob_2_Detail.Text = '1';
            app.HzKnob_3_Detail = uilabel(app.UIFigure);
            app.HzKnob_3_Detail.Text = '1';
            app.HzKnob_4_Detail = uilabel(app.UIFigure);
            app.HzKnob_4_Detail.Text = '1';
            app.KHzKnob_1_Detail = uilabel(app.UIFigure);
            app.KHzKnob_1_Detail.Text = '1';
            app.KHzKnob_2_Detail = uilabel(app.UIFigure);
            app.KHzKnob_2_Detail.Text = '1';
            app.KHzKnob_3_Detail = uilabel(app.UIFigure);
            app.KHzKnob_3_Detail.Text = '1';
            app.KHzKnob_4_Detail = uilabel(app.UIFigure);
            app.KHzKnob_4_Detail.Text = '1';
            app.KHzKnob_5_Detail = uilabel(app.UIFigure);
            app.KHzKnob_5_Detail.Text = '1';
            app.ANALYZEButton = uibutton(app.UIFigure, 'push');
            app.ANALYZEButton.ButtonPushedFcn = createCallbackFcn(app, @ANALYZEButtonPushed, true);
            app.ANALYZEButton.Text = 'ANALYZE';
            app.ProcessingLabel = uilabel(app.UIFigure);
            app.ProcessingLabel.Visible = 'off';
            app.ProcessingLabel.Text = 'Processing...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioEqualizer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end